classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SelectpictureButton  matlab.ui.control.Button
        conv_1               matlab.ui.control.UIAxes
        batchnorm_1          matlab.ui.control.UIAxes
        relu_1               matlab.ui.control.UIAxes
        maxpool_1            matlab.ui.control.UIAxes
        conv_2               matlab.ui.control.UIAxes
        batchnorm_2          matlab.ui.control.UIAxes
        relu_2               matlab.ui.control.UIAxes
        maxpool_2            matlab.ui.control.UIAxes
        fc_1                 matlab.ui.control.UIAxes
        batchnorm_3          matlab.ui.control.UIAxes
        relu_3               matlab.ui.control.UIAxes
        fc_2                 matlab.ui.control.UIAxes
        softmax              matlab.ui.control.UIAxes
        classoutput          matlab.ui.control.UIAxes
        Label                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectpictureButton
        function SelectpictureButtonPushed(app, event)
            [file, path] = uigetfile('*.png');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path, file)]);
            end
            
            load matlab.mat;
            prev_x = 0;
            prev_y = 0;
            
            str = "";
            I = imread(fullfile(path, file));
            I = imcomplement(I);
            I = rgb2gray(I);
            BW = imbinarize(I);
            [~, n] = bwlabel(BW);
            stats = regionprops(BW, 'BoundingBox');
            
            for i = 1:n
                idx = stats(i).BoundingBox;
                
                x = idx(1);
                y = idx(2);
                w = idx(3);
                h = idx(4);
                pos = [x y w h];
                
                J = imcrop(BW, pos);
                J = imresize(J, [28 28]);
%                 J = imgaussfilt(double(J), 2);
                
                label = classify(net, J);
                
                %=========================================================
                conv_1 = activations(net, J, 'conv_1');
                batchnorm_1 = activations(net, J, 'batchnorm_1');
                relu_1 = activations(net, J, 'relu_1');
                maxpool_1 = activations(net, J, 'maxpool_1');
                
                montage(mat2gray(conv_1), 'parent', app.conv_1);
                montage(mat2gray(batchnorm_1), 'parent', app.batchnorm_1);
                montage(mat2gray(relu_1), 'parent', app.relu_1);
                montage(mat2gray(maxpool_1), 'parent', app.maxpool_1);
                
                %=========================================================
                conv_2 = activations(net, J, 'conv_2');
                batchnorm_2 = activations(net, J, 'batchnorm_2');
                relu_2 = activations(net, J, 'relu_2');
                maxpool_2 = activations(net, J, 'maxpool_2');
                
                montage(mat2gray(conv_2), 'parent', app.conv_2);
                montage(mat2gray(batchnorm_2), 'parent', app.batchnorm_2);
                montage(mat2gray(relu_2), 'parent', app.relu_2);
                montage(mat2gray(maxpool_2), 'parent', app.maxpool_2);
                
                %=========================================================
                fc_1 = activations(net, J, 'fc_1');
                batchnorm_3 = activations(net, J, 'batchnorm_3');
                relu_3 = activations(net, J, 'relu_3');
                
                montage(mat2gray(fc_1), 'parent', app.fc_1);
                montage(mat2gray(batchnorm_3), 'parent', app.batchnorm_3);
                montage(mat2gray(relu_3), 'parent', app.relu_3);
                             
                %=========================================================
                fc_2 = activations(net, J, 'fc_2');
                softmax = activations(net, J, 'softmax');
                classoutput = activations(net, J, 'classoutput');
                
                montage(mat2gray(fc_2), 'parent', app.fc_2);
                montage(mat2gray(softmax), 'parent', app.softmax);
                montage(mat2gray(classoutput), 'parent', app.classoutput);
                %=========================================================
                
                if x - prev_x > 200
                    str = " ";
                end
                
                app.Label.Text = char(64 + double(label));
                str = str + char(64 + double(label));
                
                pause(1);
                prev_x = x;
                prev_y = y;
            end
            app.Label.Text = str;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectpictureButton = uibutton(app.UIFigure, 'push');
            app.SelectpictureButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpictureButtonPushed, true);
            app.SelectpictureButton.Text = 'Select picture';
            app.conv_1 = uiaxes(app.UIFigure);
            title(app.conv_1, 'Convolution 1')
            xlabel(app.conv_1, '')
            ylabel(app.conv_1, '')
            app.batchnorm_1 = uiaxes(app.UIFigure);
            title(app.batchnorm_1, 'Batch Normalization')
            xlabel(app.batchnorm_1, '')
            ylabel(app.batchnorm_1, '')
            app.relu_1 = uiaxes(app.UIFigure);
            title(app.relu_1, 'ReLU')
            xlabel(app.relu_1, '')
            ylabel(app.relu_1, '')
            app.maxpool_1 = uiaxes(app.UIFigure);
            title(app.maxpool_1, 'Max Pooling')
            xlabel(app.maxpool_1, '')
            ylabel(app.maxpool_1, '')
            app.conv_2 = uiaxes(app.UIFigure);
            title(app.conv_2, 'Convolution 2')
            xlabel(app.conv_2, '')
            ylabel(app.conv_2, '')
            app.batchnorm_2 = uiaxes(app.UIFigure);
            title(app.batchnorm_2, 'Batch Normalization')
            xlabel(app.batchnorm_2, '')
            ylabel(app.batchnorm_2, '')
            app.relu_2 = uiaxes(app.UIFigure);
            title(app.relu_2, 'ReLU')
            xlabel(app.relu_2, '')
            ylabel(app.relu_2, '')
            app.maxpool_2 = uiaxes(app.UIFigure);
            title(app.maxpool_2, 'Max Pooling')
            xlabel(app.maxpool_2, '')
            ylabel(app.maxpool_2, '')
            app.fc_1 = uiaxes(app.UIFigure);
            title(app.fc_1, 'Fully Connected')
            xlabel(app.fc_1, '')
            ylabel(app.fc_1, '')
            app.batchnorm_3 = uiaxes(app.UIFigure);
            title(app.batchnorm_3, 'Batch Normalization')
            xlabel(app.batchnorm_3, '')
            ylabel(app.batchnorm_3, '')
            app.relu_3 = uiaxes(app.UIFigure);
            title(app.relu_3, 'ReLU')
            xlabel(app.relu_3, '')
            ylabel(app.relu_3, '')
            app.fc_2 = uiaxes(app.UIFigure);
            title(app.fc_2, 'Fully Connected')
            xlabel(app.fc_2, '')
            ylabel(app.fc_2, '')
            app.softmax = uiaxes(app.UIFigure);
            title(app.softmax, 'Softmax')
            xlabel(app.softmax, '')
            ylabel(app.softmax, '')
            app.classoutput = uiaxes(app.UIFigure);
            title(app.classoutput, 'Classification Output')
            xlabel(app.classoutput, '')
            ylabel(app.classoutput, '')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end